classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SimulationControls  simulink.ui.control.SimulationControls
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        modelName % Description
        listenerTimer
    end
    
    
    methods (Access = private)
        function updateUI(app)
            try
                val = get_param([app.modelName '/Env_Label'],'RuntimeObject');
                app.TextArea.Value = sprintf('%.2f', val.OutputPort(1).Data);
            catch
                % 시뮬레이션 종료 시 에러 방지
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.modelName = 'GaitAlgorithm_arduino_V5_2024b';
            set_param(app.modelName, 'SimulationCommand', 'start');
            app.listenerTimer = timer('ExecutionMode','fixedRate','Period',0.05, ...
                                  'TimerFcn', @(~,~)updateUI(app));
            start(app.listenerTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Associate the Simulink Model
            app.Simulation = simulation('GaitAlgorithm_arduino_V5_2024b');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end